classdef DigitalTwinApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ClearPlotsButton               matlab.ui.control.Button
        LoadDatafromURLButton          matlab.ui.control.Button
        EnterCloudDatasetURLoptionalEditField  matlab.ui.control.EditField
        EnterCloudDatasetURLoptionalEditFieldLabel  matlab.ui.control.Label
        LoadDatafromPCButton           matlab.ui.control.Button
        RunSimulationButton            matlab.ui.control.Button
        CurrentFrequencyradsEditField  matlab.ui.control.NumericEditField
        CurrentFrequencyradsEditFieldLabel  matlab.ui.control.Label
        CurrentAmplitudeAEditField     matlab.ui.control.NumericEditField
        CurrentAmplitudeAEditFieldLabel  matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function toggleVisibility(app, src, event)
            % Get the line corresponding to the clicked legend item
            lineHandle = event.Peer;
        
            % Toggle visibility
            if strcmp(lineHandle.Visible,'on')
                lineHandle.Visible = 'off';
            else
                lineHandle.Visible = 'on';
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
    % Read UI input
    %N = app.SpindlespeedrpmEditField.Value;
    %assignin('base','N',N);

    CurrentFreqHz = app.CurrentFrequencyradsEditField.Value;
    assignin('base','CurrentFreqHz', CurrentFreqHz);

    CurrentAmplitude = app.CurrentAmplitudeAEditField.Value;
    assignin('base','CurrentAmplitude', CurrentAmplitude);


    % Run the Simulink model
    simOut = sim('DigitalTwin_withMA_Mask');

    % Extract time
    t = simOut.t_out;

    % Extract displacement
    y = simOut.y_out;
    yd = simOut.yd_out;
    ydd = simOut.ydd_out;

    % Extract cutting force
    Fc_est = simOut.Fc_est;

    % Plot time signal
    cla(app.UIAxes2);
    hold(app.UIAxes2, 'on');
    
    p1 = plot(app.UIAxes2, t, y,   'DisplayName','y');
    p2 = plot(app.UIAxes2, t, yd,  'DisplayName','yd');
    p3 = plot(app.UIAxes2, t, ydd, 'DisplayName','ydd');
    
    hold(app.UIAxes2, 'on');
    title(app.UIAxes2, "Model Output");
    xlabel(app.UIAxes2, "Time (s)");
    ylabel(app.UIAxes2, "m, m/s, m/s^2");

    
    lgd = legend(app.UIAxes2,'show');
    lgd.ItemHitFcn = @(src,event) app.toggleVisibility(src, event);   % Enable hide/show

    % Compute FRF
    fs = 1/(t(2)-t(1));
    [f, H] = computeFRF(y, fs);

    % Plot FRF
    plot(app.UIAxes, f, H);
    title(app.UIAxes, "Model FRF");
    xlabel(app.UIAxes, "Frequency (Hz)");
    ylabel(app.UIAxes, "Magnitude");

    % Plot estimated cutting force
    hold(app.UIAxes_2, 'on');
    plot(app.UIAxes_2, t, Fc_est)
    title(app.UIAxes_2, "Estimated Cutting Force Fc");
    xlabel(app.UIAxes_2, "Time (s)");
    ylabel(app.UIAxes_2, "Magnitude (N)");
        end

        % Button pushed function: LoadDatafromPCButton
        function LoadDatafromPCButtonPushed(app, event)
    [file, path] = uigetfile('*.csv', 'Select Cloud Dataset');
    if isequal(file,0)
        return;
    end

    T = readtable(fullfile(path, file));
    t = T.time;
    y = T.acc;

    % Plot time data
    hold(app.UIAxes2, 'on');
    plot(app.UIAxes2, t, y);
    title(app.UIAxes2, "PC Data - Time Domain");
    xlabel(app.UIAxes2, "Time (s)");
    ylabel(app.UIAxes2, "Acceleration (m/s^2)");
    % Compute FRF
    fs = 1/(t(2)-t(1));
    [f, H] = computeFRF(y, fs);

    % Plot FRF
    hold(app.UIAxes, 'on');
    plot(app.UIAxes, f, H);
    title(app.UIAxes, "PC Data FRF");
    xlabel(app.UIAxes, "Frequency (Hz)");
    ylabel(app.UIAxes, "Magnitude");
        end

        % Button pushed function: LoadDatafromURLButton
        function LoadDatafromURLButtonPushed(app, event)
            
            % Get URL from text field
            url = app.EnterCloudDatasetURLoptionalEditField.Value;
        
            % Basic validation
            if url == ""
                uialert(app.UIFigure, "Please paste a CSV URL.", "No URL Entered");
                return;
            end
        
            try
                % Try to read CSV from cloud
                T = readtable(url);
            catch ME
                uialert(app.UIFigure, "Could not load data from the provided URL. Check the link and internet connection.", ...
                    "Cloud Load Error");
                return;
            end
        
            % Extract fields (expecting 'time' and 'acc' columns)
            if ~all(ismember({'time','acc'}, T.Properties.VariableNames))
                uialert(app.UIFigure, "CSV file must contain 'time' and 'acc' columns.", "Invalid CSV Format");
                return;
            end
        
            t = T.time;
            y = T.acc;
        
            % Plot time-domain signal
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, t, y);
            title(app.UIAxes2, "URL Cloud Data - Time Domain");
            xlabel(app.UIAxes2, "Time (s)");
            ylabel(app.UIAxes2, "Acceleration (m/s^2)");
        
            % Compute FRF
            fs = 1/(t(2)-t(1));
            [f, H] = computeFRF(y, fs);
        
            % Plot FRF
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, f, H);
            title(app.UIAxes, "URL Cloud Data - FRF");
            xlabel(app.UIAxes, "Frequency (Hz)");
            ylabel(app.UIAxes, "Magnitude");

        end

        % Button pushed function: ClearPlotsButton
        function ClearPlotsButtonPushed(app, event)
            cla(app.UIAxes);   % Clear FRF axes
            cla(app.UIAxes2);  % Clear time-domain axes
            cla(app.UIAxes_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'FRFAxes')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'TimeAxes')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'FcAxes')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.CurrentAmplitudeAEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentAmplitudeAEditFieldLabel.Text = 'Current Amplitude (A)';
            app.CurrentAmplitudeAEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentAmplitudeAEditField.Value = 150;
            app.CurrentFrequencyradsEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentFrequencyradsEditFieldLabel.Text = 'Current Frequency (rad/s)';
            app.CurrentFrequencyradsEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentFrequencyradsEditField.Value = 250;
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.LoadDatafromPCButton = uibutton(app.UIFigure, 'push');
            app.LoadDatafromPCButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatafromPCButtonPushed, true);
            app.LoadDatafromPCButton.Text = 'Load Data from PC';
            app.EnterCloudDatasetURLoptionalEditFieldLabel = uilabel(app.UIFigure);
            app.EnterCloudDatasetURLoptionalEditFieldLabel.Text = {'Enter Cloud Dataset URL '; '(optional)'};
            app.EnterCloudDatasetURLoptionalEditField = uieditfield(app.UIFigure, 'text');
            app.LoadDatafromURLButton = uibutton(app.UIFigure, 'push');
            app.LoadDatafromURLButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatafromURLButtonPushed, true);
            app.LoadDatafromURLButton.Text = 'Load Data from URL';
            app.ClearPlotsButton = uibutton(app.UIFigure, 'push');
            app.ClearPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotsButtonPushed, true);
            app.ClearPlotsButton.Text = 'Clear Plots';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DigitalTwinApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end